classdef Clueless < matlab.apps.AppBase
    %CLUELESS Lord Ned's Clueless Game
    %   Inspired by the Problem 61069 of Cody Content 2025: Clueless - Lord
    %   Ned in the Game Room

    % Properties that correspond to app components
    properties (Access = public)
        clFigure         matlab.ui.Figure
        Toolbar          matlab.ui.container.Toolbar
        LoadGameMat      matlab.ui.container.toolbar.PushTool
        SaveGameMat      matlab.ui.container.toolbar.PushTool
        AppTheme         matlab.ui.container.toolbar.ToggleTool
        About            matlab.ui.container.toolbar.PushTool
        GclBoard         matlab.ui.container.GridLayout
        clBoard          matlab.ui.container.Panel
        GclLayout        matlab.ui.container.GridLayout
        MovesListPanel   matlab.ui.container.Panel
        Gmoves           matlab.ui.container.GridLayout
        NextMove         matlab.ui.control.Button
        AskButton        matlab.ui.control.Button
        ReplyDD          matlab.ui.control.DropDown
        AskC3            matlab.ui.control.Spinner
        AskC2            matlab.ui.control.Spinner
        AskC1            matlab.ui.control.Spinner
        AskPlayer        matlab.ui.control.DropDown
        Moves            matlab.ui.control.Table
        CardsDistPanel   matlab.ui.container.Panel
        Gcards           matlab.ui.container.GridLayout
        Check            matlab.ui.control.Button
        EnvelopeC3       matlab.ui.control.Spinner
        EnvelopeC2       matlab.ui.control.Spinner
        EnvelopeC1       matlab.ui.control.Spinner
        EnvelopeCsLabel  matlab.ui.control.Label
        CommonC3         matlab.ui.control.EditField
        CommonC2         matlab.ui.control.EditField
        CommonC1         matlab.ui.control.EditField
        CommonCsLabel    matlab.ui.control.Label
        PlayersC3        matlab.ui.control.EditField
        PlayersC2        matlab.ui.control.EditField
        PlayersC1        matlab.ui.control.EditField
        PlayersCsLabel   matlab.ui.control.Label
        GameSetupPanel   matlab.ui.container.Panel
        Gsetup           matlab.ui.container.GridLayout
        ModeSwitch       matlab.ui.control.Switch
        LetsPlay         matlab.ui.control.Button
        NumYou           matlab.ui.control.Spinner
        NumYouLabel      matlab.ui.control.Label
        NumPlayers       matlab.ui.control.Spinner
        NumPlayersLabel  matlab.ui.control.Label
        NumCards         matlab.ui.control.Spinner
        NumCardsLabel    matlab.ui.control.Label
        NumCats          matlab.ui.control.Spinner
        LoadGame         matlab.ui.control.DropDown
        NumCatsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Obvious Variables
        games % Pre-loaded games to solve
        levels % Game levels to play
        plyrCards % (no. of players)×(no. of categories) cell array
        yourCards % 1×(no. of categories) cell array
        commonCards % 1×(no. of categories) cell array
        moves % (no. of moves)×(no. of cat.+2)×(no. of players) double array
        movesTable % moves translated to the table version for "You"
        reply % a dictionary translating the last column of moves to movesTable (defined in startup)
        solV % current game's solution (NOT to be confused with solE defined below!)
        params % current game's parameters ([categories, players, cards, "you"]) used for saving a game!
        %% Extra Variables
        PlayersC % All players' cards: (no. of players)×(no. of categories) edit fields
        CommonC % Common cards: 1×(no. of categories) edit fields
        EnvelopeC % Envelope's card spinners: 1×(no. of categories) spinners
        AskC % Ask card spinners: 1×(no. of categories) spinners
        %% App Colours & Styles
        catColor = [0.18,0.75,0.94;0.23,0.67,0.20;0.82,0.02,0.55;0.93,0.69,0.13;0.52,0.09,0.82]
        catColorHex = ["#2EBFF0","#3BAB33","#D1058C","#EDB021","#8517D1"]
        catStyle = @(col)uistyle("BackgroundColor",col,FontColor='w')
        plyColor = [0.75 0.36 0.98]
        plyStyle = uistyle("BackgroundColor",[0.75 0.36 0.98])
        %% App Info
        majVer = 1 % Major version
        minVer = 2 % Minor version
        prog = 22 %χ (Never change this!)
        relz = "2" % Release; always string (keep space between number & letter like "1 B")
        relzDate = datetime(2026,2,21) % Release date
        %% .NET stuff
        speaker = [] % :)
        %% Files & Folders
        mfilepath = fileparts(mfilename('fullpath')) % Path to MLAPP!
        curpath = pwd() % Startup folder
        clmat = [] % "Settings" file
    end

    properties (Dependent, Access = private)
        num3 %No. of categories
        numC %No. of cards per category
        numP %No. of players
        numY %"Your" player #
        solE %Solution from the spinners!
    end

    methods %for Dependent properties
        function t = get.num3(app)
            t=app.NumCats.Value;
        end

        function n = get.numC(app)
            n=app.NumCards.Value;
        end

        function m = get.numP(app)
            m=app.NumPlayers.Value;
        end
        
        function y = get.numY(app)
            y=app.NumYou.Value;
        end

        function s = get.solE(app)
            s=[app.EnvelopeC(:).Value];
        end
    end
    
    methods (Access = private)
        function trychk(app, event, funcall)
            try
                funcall(app, event);
            catch err
                if ~isempty(app.speaker)
                    SpeakAsync(app.speaker,'Well, that was unexpected!');
                end
                detail = struct2cell(err.stack)';
                detail = strcat(detail(:,2), ' in Line-', cellstr(cellfun(@num2str, detail(:,3), UniformOutput=0)));
                uialert(app.clFigure, [[err.identifier, ': ']; err.message; flipud(detail)], ...
                    "Something Went Wrong!😱", Icon="error");
            end
        end

        function gameGenerator(app)
            %% Generate cards & pick cards for envelope (Solution)
            t = app.num3;
            m = app.numP;
            n = app.numC;
            app.params = [t, m, n, app.numY];
            cards = (1:t)'*10 + (1:n);
            app.solV = randi(n, [1, t]);
            %% Shuffle the cards for distribution
            cards = cards(~ismember(cards, (1:t)*10 + app.solV));
            nC2D = t*(n-1); % No. of cards to distribute
            cards = cards(randperm(nC2D));
            nCC = mod(nC2D, m); % No. of common cards
            %% Players get the cards
            for j = 1:m
                for k = j:m:nC2D-nCC
                    app.plyrCards{j, floor(cards(k)/10)}(end+1) = mod(cards(k), 10);
                end
            end
            app.yourCards = app.plyrCards(app.numY, :);
            %% Remaining common cards
            for j = 0:nCC-1
                app.commonCards{floor(cards(end-j)/10)}(end+1) = mod(cards(end-j), 10);
            end
        end

        function cardsDistributor(app)
            %% Prepare columns of cards in both Cards and Moves panels
            coldiff = width(app.PlayersC) - app.num3;
            app.Check.Layout.Column(2) = app.Check.Layout.Column(2) - coldiff;
            app.Moves.Layout.Column(2) = app.Moves.Layout.Column(2) - coldiff;
            app.NextMove.Layout.Column(2) = app.NextMove.Layout.Column(2) - coldiff;
            switch sign(coldiff)
                case 1 % Remove Category Column(s)
                    delete(app.PlayersC(:, end-coldiff+1:end));
                    app.PlayersC(:, end-coldiff+1:end) = [];
                    delete(app.CommonC(end-coldiff+1:end));
                    app.CommonC(end-coldiff+1:end) = [];
                    delete(app.EnvelopeC(end-coldiff+1:end));
                    app.EnvelopeC(end-coldiff+1:end) = [];
                    app.Gcards.ColumnWidth = repelem({'fit', '1x'}, [1, app.num3]);
                    app.Moves.ColumnName(end-coldiff:end-1) = [];
                    delete(app.AskC(end-coldiff+1:end));
                    app.AskC(end-coldiff+1:end) = [];
                    app.AskButton.Layout.Column = app.num3+2;
                    app.ReplyDD.Layout.Column = app.num3+2;
                    app.Gmoves.ColumnWidth = repelem({'1x'}, app.num3+2);
                case -1 % Add Category Column(s)
                    app.Gcards.ColumnWidth = repelem({'fit', '1x'},[1, app.num3]);
                    app.Gmoves.ColumnWidth = repelem({'1x'}, app.num3+2);
                    app.AskButton.Layout.Column = app.num3+2;
                    app.ReplyDD.Layout.Column = app.num3+2;
                    for c = 1:-coldiff
                        pcol = c + app.num3 + coldiff;
                        app.PlayersC(:,end+1) = copyobj(app.PlayersC(:, end), app.Gcards);
                        set(app.PlayersC(:, end), BackgroundColor=app.catColor(pcol, :));
                        for p=1 : height(app.PlayersC)
                            app.PlayersC(p, end).Layout.Column = 1+pcol;
                        end
                        app.CommonC(end+1) = copyobj(app.CommonC(end), app.Gcards);
                        app.CommonC(end).BackgroundColor = app.catColor(pcol, :);
                        app.CommonC(end).Layout.Column = 1+pcol;
                        app.EnvelopeC(end+1) = copyobj(app.EnvelopeC(end), app.Gcards);
                        app.EnvelopeC(end).BackgroundColor = app.catColor(pcol, :);
                        app.EnvelopeC(end).Layout.Column = 1+pcol;
                        app.Moves.ColumnName(end+1) = app.Moves.ColumnName(end);
                        app.Moves.ColumnName{end-1} = ['Cat.', num2str(pcol)];
                        app.AskC(end+1) = copyobj(app.AskC(end), app.Gmoves);
                        app.AskC(end).BackgroundColor = app.catColor(pcol, :);
                        app.AskC(end).Layout.Column = 1+pcol;
                        uistack(app.NextMove, "bottom");
                    end
                otherwise
                    %Do nothing
            end
            %% Prepare rows of players
            rowdiff = height(app.PlayersC) - app.numP;
            if rowdiff ~= 0
                app.Check.Layout.Row = app.numP+3;
                app.EnvelopeCsLabel.Layout.Row = app.numP+2;
                app.CommonCsLabel.Layout.Row = app.numP+1;
                for c = 1:app.num3
                    app.EnvelopeC(c).Layout.Row = app.numP+2;
                    app.CommonC(c).Layout.Row = app.numP+1;
                end
            end
            switch sign(rowdiff)
                case 1 % Remove Players
                    delete(app.PlayersCsLabel(end-rowdiff+1:end));
                    app.PlayersCsLabel(end-rowdiff+1:end) = [];
                    delete(app.PlayersC(end-rowdiff+1:end, :));
                    app.PlayersC(end-rowdiff+1:end, :) = [];
                    app.Gcards.RowHeight = repelem({'1x', 45}, [app.numP+2, 1]);
                case -1 % Add Players
                    app.Gcards.RowHeight = repelem({'1x', 45}, [app.numP+2, 1]);
                    for r = 1:-rowdiff
                        prow = r + app.numP + rowdiff;
                        app.PlayersCsLabel(end+1) = copyobj(app.PlayersCsLabel(1), app.Gcards);
                        app.PlayersCsLabel(end).Layout.Row = prow;
                        app.PlayersCsLabel(end).Text = replace(app.PlayersCsLabel(1).Text, "1", num2str(prow));
                        app.PlayersC(end+1, :) = copyobj(app.PlayersC(end, :), app.Gcards);
                        for p = 1:width(app.PlayersC)
                            app.PlayersC(end, p).Layout.Row = prow;
                        end
                    end
                otherwise
                    %Do nothing
            end
            %% Set the cards for all the players, the common cards & the envelope
            set(app.PlayersCsLabel(:), BackgroundColor="none");
            app.PlayersCsLabel(app.numY).BackgroundColor = app.plyColor;
            for p = 1:app.numP
                app.PlayersCsLabel(p).Tooltip = "Player " + p;
            end
            app.PlayersCsLabel(app.numY).Tooltip = "It's You!";
            set(app.PlayersC(:), Value="{?}");
            for c = 1:app.num3
                app.PlayersC(app.numY, c).Value = "[" + strjoin(string(app.yourCards{c}), ",") + "]";
                app.CommonC(c).Value = "[" + strjoin(string(app.commonCards{c}), ",") + "]";
            end
            set(app.EnvelopeC(:), Value=0);
            removeStyle(app.Moves);
            addStyle(app.Moves, uistyle("Interpreter", "html"));
            addStyle(app.Moves, uistyle("HorizontalAlignment", "center"), "column", 1:app.num3+3);
            for c = 1:app.num3
                addStyle(app.Moves, app.catStyle(app.catColor(c, :)), "column", c+2);
            end
        end

        function moveLoader(app)
            %% Prepare "your" moves table to be used to fill the Moves uitable:
            if ~isempty(app.moves)
                app.movesTable = table("👤" + (1+mod(0:height(app.moves)-1, app.numP)'), ...
                    "👤" + app.moves(:, 1, app.numY));
                for c = 1:app.num3
                    app.movesTable(:, end+1) = table(app.moves(:, c+1, app.numY));
                end
                app.movesTable(:, end+1) = table(app.reply(app.moves(:, end, app.numY)));
            else
                app.movesTable = table();
            end
            app.Moves.Data = [];
            %% Prepare "Ask" & being asked UI elements:
            if app.ModeSwitch.ValueIndex == 1 && app.LoadGame.Value > 0 % Play Mode & Non-easy levels
                % Prepare 'Pick a Player' dropdown...
                a = 1:app.numP-1;
                a = a + (a >= app.numY);
                app.AskPlayer.Items = "👤" + a';
                app.AskPlayer.ItemsData = a;
                % Prepare 'Your Reply' dropdown...
                app.ReplyDD.Items = {'<font color=#FF000>None</font>', 'Reply➡️'};
                for c = 1:app.num3
                    app.ReplyDD.Items{end+1} = ['<font color=', app.catColorHex{c}, '>Cat.', num2str(c), '</font>'];
                end
                app.ReplyDD.ItemsData = -1:app.num3;
                app.ReplyDD.Value = 0;
            end
        end

        function moveGenerator(app)
            %% This player's turn now:
            numMoves = height(app.Moves.Data);
            curply = mod(numMoves, app.numP)+1;
            %% Choose whom to ask:
            if curply ~= app.numY || ~app.LoadGame.Value % Easy level for "You" too!
                % Ask depending on the number of cards they have for non-easy levels!
                kmat = inferCards(app, curply);
                sumkmat = sum(kmat == 1, 2:3);
                maxsumkmat = max(sumkmat);
                switch app.LoadGame.Value
                    case 1 % Medium level
                        % Ask someone who has most "maybe" cards
                        pos = find(sumkmat == maxsumkmat);
                        app.moves(numMoves+1, 1, :) = pos(randi(length(pos), 1));
                    case 2 % Hard level
                        % Ask someone who has most or least "maybe" cards
                        minsumkmat = min([maxsumkmat; sumkmat(sumkmat > 0)]);
                        pos = find(sumkmat == maxsumkmat | sumkmat == minsumkmat);
                        app.moves(numMoves+1, 1, :) = pos(randi(length(pos), 1));
                    otherwise % Easy level
                        % Just randomly pick a player
                        a = randi(app.numP-1, 1);
                        app.moves(numMoves+1, 1, :) = a + (a >= curply);
                end
            else % Only for non-easy levels
                app.NextMove.Visible = "off";
                app.AskPlayer.Visible = "on";
                app.AskPlayer.Tooltip = "Pick a Player";
                focus(app.AskPlayer);
                % This surfaces the player & cards spinner for "You" to choose!
            end
            %% Ask for the cards now:
            if curply ~= app.numY || ~app.LoadGame.Value % Easy level for "You" too!
                %Only ask for cards they might have or "do not have" for non-easy levels!
                switch app.LoadGame.Value
                    case 1 % Medium level
                        % Ask for "maybe" and/or "definitely not" cards
                        for c = 1:app.num3
                            pos = setdiff(find(kmat(app.moves(end, 1, curply), :, c) <= 1), app.commonCards{c});
                            app.moves(end, c+1, :) = pos(randi(length(pos), 1));
                        end
                    case 2 % Hard level
                        % Almost same as above but try to get the "most" impact
                        for c = 1:app.num3
                            pos = setdiff(find(kmat(app.moves(end, 1, curply), :, c) == 1), app.commonCards{c});
                            if isempty(pos)
                                pos = setdiff(1:app.numC, app.commonCards{c});
                            end
                            sumkmatpos = sum(kmat(:, pos, c));
                            posk = find(sumkmatpos == min(sumkmatpos));
                            app.moves(end, c+1, :) = pos(posk(randi(length(posk), 1)));
                        end
                    otherwise % Easy level
                        % Randomly pick cards ignoring common cards
                        for c = 1:app.num3
                            a = randi(app.numC - length(app.commonCards{c}), 1);
                            app.moves(end, c+1, :) = a + sum(a >= app.commonCards{c});
                        end
                end
            else % Only for non-easy levels
                waitfor(app.AskButton, "UserData");
                app.moves(numMoves+1, 1, :) = app.AskPlayer.Value;
                app.moves(end, 2:app.num3+1, :) = repmat([app.AskC(:).Value], 1, 1, app.numP);
                app.NextMove.Visible = "on";
                app.AskPlayer.Visible = "off";
                app.AskButton.UserData = [];
            end
            %% Get the reply:
            app.moves(end, app.num3+2, :) = -1; % The asked player has NO cards by default but update below if needed!:)
            if app.moves(end, 1, curply) ~= app.numY || ~app.LoadGame.Value % Easy level
                for c = randperm(app.num3)
                    if ismember(app.moves(end, c+1, curply), app.plyrCards{app.moves(end, 1, curply), c})
                        app.moves(end, end, :) = 0;
                        app.moves(end, end, curply) = c;
                        break
                    end
                end
            else % Only for non-easy levels
                % This surfaces the player & cards spinner for "You" to reply!
                app.NextMove.Visible = "off";
                app.AskButton.Visible = "off";
                app.AskPlayer.Visible = "on";
                app.AskPlayer.Value = curply;
                app.AskPlayer.Tooltip = "Current Player Asks ""You""";
                app.AskPlayer.Enable = "off";
                for c = 1:app.num3
                    app.AskC(c).Value = app.moves(end, c+1, curply);
                end
                set(app.AskC(:), Enable="off");
                app.ReplyDD.Visible = "on";
                focus(app.ReplyDD);
                waitfor(app.ReplyDD, "Value");
                % Note down the reply after verifying!
                c = app.ReplyDD.Value;
                if c <= 0 % None
                    for c = 1:app.num3
                        if ismember(app.moves(end, c+1, curply), app.plyrCards{app.moves(end, 1, curply), c})
                            % Wrong choice: "You" have a card!
                            app.moves(end, end, :) = 0;
                            app.moves(end, end, curply) = c;
                            break
                        end
                    end
                else
                    if ismember(app.moves(end, c+1, curply), app.plyrCards{app.moves(end, 1, curply), c})
                        app.moves(end, end, :) = 0;
                        app.moves(end, end, curply) = c;
                    else
                        % Wrong choice: "You" don't have this card!
                        for c = 1:app.num3
                            if ismember(app.moves(end, c+1, curply), app.plyrCards{app.moves(end, 1, curply), c})
                                app.moves(end, end, :) = 0;
                                app.moves(end, end, curply) = c;
                                break
                            end
                        end
                    end
                end
                app.NextMove.Visible = "on";
                app.AskPlayer.Visible = "off";
                app.AskButton.Visible = "on";
                app.AskPlayer.Enable = "on";
                set(app.AskC(:), Enable="on");
                app.ReplyDD.Visible = "off";
                app.ReplyDD.Value = 0;
            end
            %% Save this move's info in movesTable (will appear in the Moves uitable):
            temp = table("👤" + curply, "👤" + app.moves(end, 1, curply));
            for c = 1:app.num3
                temp(:, c+2) = table(app.moves(end, c+1, app.numY));
            end
            temp(:, end+1) = table(app.reply(app.moves(end, end, app.numY)));
            app.movesTable(end+1, :) = temp;
        end

        function [knowMat, sol] = inferCards(app, plyr)
            % The amazing work of Stefan Abendroth, WANG Zi-Xiang, JKMSMKJ, Vasilis Bellos.
            % Made a bit "ugly" to fit the purposes of this "app".
            knowMat = double(cumsum(ones(app.numP, app.numC, app.num3)) ~= plyr);
            movesD = app.moves(1:height(app.Moves.Data), :, plyr);
            if isempty(movesD) % This is true before the first move is generated in Play Mode!
                ind = 1;
                movesDend = app.numP+1;
            else
                ind = movesD(:, 1) + app.numP*((0:(app.num3-1))*app.numC + movesD(:, 2:(app.num3+1)) - 1);
                movesDend = movesD(:, end);
            end
            knowMat(ind(movesDend < 0, :)) = 0;
            for cat = 1:app.num3
                cardscat = [app.plyrCards{plyr,cat}, app.commonCards{cat}];
                knowMat(:, cardscat, cat) = repmat(2*(1:app.numP == plyr)', 1, length(cardscat));
            end
            plyCards = numel(cell2mat(app.yourCards));
            for cat = 1:2*app.num3
                maxKnowMat = max(knowMat);
                knowMat = knowMat.*2.^(sum(knowMat > 0, 2:3) == plyCards).*2.^(sum(knowMat > 0, 1:2) == app.numC-1).*...
                    (knowMat == maxKnowMat & (sum(knowMat > 1, 2:3) == plyCards) < knowMat &...
                    (sum(maxKnowMat > 1) == app.numC-1) < knowMat);
                knowMat(ind(1:app.num3 == movesDend | ~movesDend & sum(knowMat(ind), 2) == 1 & knowMat(ind))) = 2;
            end
            [sol, ~] = find(squeeze(maxKnowMat == 0));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Loading / Saving startup folder & settings...
            if isdeployed() % Only for standalone program on Windows!
                app.clmat = fullfile(getenv('appdata'), 'Clueless', 'application', 'cl.mat');
                if exist(app.clmat, 'file') == 2
                    load(app.clmat, 'SuF', 'Opts');
                    app.curpath = SuF;
                    if ~isMATLABReleaseOlderThan("R2025a")
                        app.AppTheme.Enable = "on";
                        if isfield(Opts, 'appTheme') && app.AppTheme.State ~= Opts.appTheme
                            app.AppTheme.State = Opts.appTheme;
                            AppThemeClicked(app, []);
                        end
                    end
                else
                    SuF = app.curpath;
                    if ~isMATLABReleaseOlderThan("R2025a")
                        app.AppTheme.Enable = "on";
                    end
                    Opts.appTheme = app.AppTheme.State;
                    try
                        if exist(fullfile(getenv('appdata'), 'Clueless', 'application'), 'dir') ~= 7
                            mkdir(fullfile(getenv('appdata'), 'Clueless'), 'application');
                        end
                        save(app.clmat, 'SuF', 'Opts');
                    catch error
                        uialert(app.clFigure,error.message, "No Write-Access!😯", Icon="warning");
                    end
                end
            else
                if ~isMATLABReleaseOlderThan("R2025a")
                    app.AppTheme.Enable = "on";
                    if app.clFigure.Theme.BaseColorStyle == "dark"
                        app.AppTheme.State = "on";
                        AppThemeClicked(app, []);
                    end
                end
            end
            %% Setting up the GUI
            h = uiprogressdlg(app.clFigure, Title="Starting up...", Message="Please wait a moment...", ...
                Indeterminate="on");
            app.clFigure.WindowState = "maximized";drawnow
            load("CluelessGames.mat", "CG");
            app.LoadGame.Items = ["Select a Game:", [CG(:).title]];
            app.LoadGame.ItemsData = -1:length(CG)-1;
            app.games = CG;
            app.levels = ["I don't think. No way!"; "I will think! Why not?"; "I think winning is overrated..."];
            app.plyrCards = cell(app.numP, app.num3);
            app.yourCards = cell(1, app.num3);
            app.commonCards = cell(1, app.num3);
            app.moves = zeros(0, 0, app.numP);
            app.movesTable = table();
            app.reply = dictionary(-1, "<font color=#FF000>NO</font>", ...
                0, "<font color="+app.catColorHex(1)+">Y</font>" + "<font color="+app.catColorHex(2)+">E</font>" + ...
                "<font color="+app.catColorHex(3)+">S</font>", ...
                1, "<font color="+app.catColorHex(1)+">Cat.1</font>", ...
                2, "<font color="+app.catColorHex(2)+">Cat.2</font>", ...
                3, "<font color="+app.catColorHex(3)+">Cat.3</font>", ...
                4, "<font color="+app.catColorHex(4)+">Cat.4</font>", ...
                5, "<font color="+app.catColorHex(5)+">Cat.5</font>");
            app.solV = [];
            app.params = [];
            app.PlayersC = [app.PlayersC1, app.PlayersC2, app.PlayersC3];
            app.CommonC = [app.CommonC1, app.CommonC2, app.CommonC3];
            app.EnvelopeC = [app.EnvelopeC1, app.EnvelopeC2, app.EnvelopeC3];
            app.AskC = [app.AskC1, app.AskC2, app.AskC3];
            app.AskButton.UserData = [];
            addStyle(app.ReplyDD, uistyle("Interpreter", "html"));
            cardsDistributor(app);
            %% Assigning callbacks via try-catch
            allGUIobjs = findobj(app.clFigure);
            for g = 1:length(allGUIobjs)
                propg = properties(allGUIobjs(g));
                propg = propg(contains(propg, ["Fcn", "Callback"])); % uitables have CellEditCallback!
                for c = propg'
                    cllbck = allGUIobjs(g).(c{1});
                    if ~isempty(cllbck)
                        allGUIobjs(g).(c{1}) = @(s, e)trychk(app, e, cllbck);
                    end
                end
            end
            %% Finishing up...
            set(h, Message="GUI set up.", Icon="success");drawnow
            try
                NET.addAssembly('System.Speech');
                app.speaker = System.Speech.Synthesis.SpeechSynthesizer;
                SpeakAsync(app.speaker, 'Lord Ned in the Game Room! Let''s Play Clueless!');
            catch me
                getReport(me, 'extended');
            end
            close(h);
            focus(app.LoadGame);
        end

        % Close request function: clFigure
        function closeupFcn(app, event)
            if ~isempty(app.speaker)
                SpeakAsync(app.speaker, 'Exit, Really?');
            end
            BName = uiconfirm(app.clFigure, "Do you want to exit this Clueless game?", "Exit?", ...
                Options=["YES", "NO"], DefaultOption=2, CancelOption=2);
            switch BName
                case "YES"
                    if ~isempty(app.speaker)
                        SpeakAsync(app.speaker, 'Lord Ned is NOT pleased!');
                    end
                    if exist(app.clmat, 'file') == 2
                        SuF = app.curpath;
                        save(app.clmat, 'SuF', '-append');
                        if app.AppTheme.Enable
                            load(app.clmat, 'Opts');
                            if Opts.appTheme ~= app.AppTheme.State
                                Opts.appTheme = ~Opts.appTheme;
                                save(app.clmat, 'Opts', '-append');
                            end
                        end
                    end
                    closereq
                case "NO"
                    if ~isempty(app.speaker)
                        SpeakAsync(app.speaker, 'Lord Ned is pleased!');
                    end
            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if app.ModeSwitch.ValueIndex == 1 % Play Mode
                app.LoadGameMat.Enable = "off";
                app.LoadGame.Items = ["Select a Level:"; app.levels];
                app.LoadGame.ItemsData = -1:length(app.levels)-1;
                app.LoadGame.Tooltip = "Select a Level";
                app.NumCats.Enable = "on";
                app.NumCards.Enable = "on";
                app.NumPlayers.Enable = "on";
                app.NumYou.Enable = "on";
            else % Solve Mode
                app.LoadGameMat.Enable = "on";
                app.LoadGame.Items = ["Select a Game:"; [app.games(:).title]'];
                app.LoadGame.ItemsData = -1:length(app.games)-1;
                app.LoadGame.Tooltip = "Select a Game";
                app.NumCats.Enable = "off";
                app.NumCards.Enable = "off";
                app.NumPlayers.Enable = "off";
                app.NumYou.Enable = "off";
            end
            app.LoadGame.Value = -1;
            focus(app.LoadGame);
        end

        % Value changed function: NumCats
        function NumCatsValueChanged(app, event)
            app.NumCards.Limits(1) = max([4, ceil(1+(2*app.numP/app.num3))]); % Make sure 2 cards are with each player!
            NumCardsValueChanged(app, []); % If the limit change altered the value too!
        end

        % Value changed function: NumCards
        function NumCardsValueChanged(app, event)
            % NumCards sets the upper limit of Envelope & Ask spinners
            set(app.EnvelopeC(:), Limits=[0, app.numC]);
            set(app.AskC(:), Limits=[1, app.numC]);
        end

        % Value changed function: NumPlayers
        function NumPlayersValueChanged(app, event)
            app.NumCards.Limits(1) = max([4, ceil(1+(2*app.numP/app.num3))]); % Make sure 2 cards are with each player!
            NumCardsValueChanged(app, []); % If the limit change altered the value too!
            app.NumYou.Limits(2) = app.numP; % NumPlayers sets the upper limit of "your" player #
        end

        % Button pushed function: LetsPlay
        function LetsPlayButtonPushed(app, event)
            if app.LoadGame.Value == -1
                return
            end
            if app.ModeSwitch.ValueIndex == 2 % Solve Mode: Just load a game and wait for "Next Move"
                gID = 1+app.LoadGame.Value;
                app.NumCats.Value = app.games(gID).t;
                NumCatsValueChanged(app, []);
                app.NumPlayers.Value = app.games(gID).m;
                NumPlayersValueChanged(app, []);
                app.NumCards.Value = app.games(gID).n;
                NumCardsValueChanged(app, []);
                app.NumYou.Value = app.games(gID).pnum;
                app.plyrCards = cell(app.numP, app.num3);
                app.yourCards = app.games(gID).yourcards;
                app.plyrCards(app.numY,:)=app.yourCards;
                app.commonCards = app.games(gID).commoncards;
                app.moves = zeros(0, 0, app.numP);
                app.moves(1:height(app.games(gID).moves), 1:width(app.games(gID).moves), app.numY) = ...
                    app.games(gID).moves;
                app.solV = app.games(gID).sol;
                app.params = [app.num3, app.numP, app.numC, app.numY];
            else % Play Mode: Just generate a game and wait for "Next Move"
                app.plyrCards = cell(app.numP, app.num3);
                app.yourCards = cell(1, app.num3);
                app.commonCards = cell(1, app.num3);
                app.moves = zeros(0, 0, app.numP);
                app.solV = [];
                app.params = [];
                gameGenerator(app);
            end
            cardsDistributor(app);
            moveLoader(app);
            %(De)Activate Panels
            app.GameSetupPanel.Enable = "off";
            if app.ModeSwitch.ValueIndex == 2
                app.NumCats.Enable = "on";
                app.NumCards.Enable = "on";
                app.NumPlayers.Enable = "on";
                app.NumYou.Enable = "on";
            end
            app.CardsDistPanel.Enable = "on";
            app.MovesListPanel.Enable = "on";
        end

        % Button pushed function: NextMove
        function NextMoveButtonPushed(app, event)
            if app.ModeSwitch.ValueIndex==1 % Play Mode
                % Generate a move
                moveGenerator(app);
                app.Moves.Data = [app.Moves.Data; app.movesTable{end, :}];
            else % Solve Mode
                % Populate the table with moves one-by-one
                numMoves = height(app.Moves.Data);
                if numMoves == height(app.moves)
                    uialert(app.clFigure, ["These are all the moves!"; "Guess the cards in the envelope now."],...
                        "Time for a solution!🤔", Icon="info");
                    return
                else
                    app.Moves.Data = [app.Moves.Data; app.movesTable{numMoves+1, :}];
                end
            end
            % Highlight "You" in the first two columns of the Moves uitable:
            col = find(contains(app.Moves.Data(end, 1:2), string(app.numY)));
            if ~isempty(col)
                addStyle(app.Moves, app.plyStyle, "cell", [height(app.Moves.Data), col]);
            end
            scroll(app.Moves, "bottom");
            % Fill in the inferences till this point!
            kmat = inferCards(app, app.numY);
            for c = 1:app.num3
                for m = setdiff(1:app.numP, app.numY)
                    app.PlayersC(m, c).Value = erase(strcat("{" + strjoin(string(find(squeeze(kmat(m, :, c)) == 1)),...
                        ",") + "}", "[" + strjoin(string(find(squeeze(kmat(m, :, c)) > 1)), ",") + "]"), "{}");
                end
            end
            drawnow
            % Check if the current player have figured it out!
            if app.ModeSwitch.ValueIndex == 1 && app.LoadGame.Value == length(app.levels)-1 % Max level in Play Mode
                curply = mod(height(app.Moves.Data)-1, app.numP)+1;
                if curply ~= app.numY
                    [~, sol2chk] = inferCards(app, curply);
                    if isequal(app.solV(:)', sol2chk(:)') % Check the envelope
                        for c = 1:app.num3
                            app.EnvelopeC(c).Value = app.solV(c);
                        end
                        uialert(app.clFigure, ["Clueless No More! Congratulations!🎉"; ...
                            "👤" + curply + " Did It!🥳"], "Game Over!👏", Icon="success");
                    end
                end
                % End the game or continue till "You" figure it out?
            end
        end

        % Button pushed function: AskButton
        function AskButtonButtonPushed(app, event)
            app.AskButton.UserData = 1; % Returns to moveGenerator()!
        end

        % Button pushed function: Check
        function CheckButtonPushed(app, event)
            winner = "You";
            if app.ModeSwitch.ValueIndex == 1 && app.LoadGame.Value == length(app.levels)-1 % Max level in Play Mode
                if mod(height(app.Moves.Data)-1, app.numP)+1 == app.numY % Is it Your turn?
                    winner = "👤" + app.NumYou.Value;
                else
                    uialert(app.clFigure, "Wait for your turn!🤫", "Clueless As Ever!😔", Icon="warning");
                    return
                end
            end
            if isequal(app.solV(:)', app.solE) % Check the envelope
                uialert(app.clFigure, ["Clueless No More! Congratulations!🎉"; winner + " Did It!🥳"], ...
                    "Game Over!👏", Icon="success");
                % Do not reset the game data now! Leave it for "save" functionality...
            else % Keep trying
                uialert(app.clFigure, "Sorry! Keep Trying...", "Clueless As Ever!😔", Icon="info");
                return
            end
            % (De)Activate Panels
            app.GameSetupPanel.Enable = "on";
            if app.ModeSwitch.ValueIndex == 2
                app.NumCats.Enable = "off";
                app.NumCards.Enable = "off";
                app.NumPlayers.Enable = "off";
                app.NumYou.Enable = "off";
            end
            app.CardsDistPanel.Enable = "off";
            app.MovesListPanel.Enable = "off";
        end

        % Callback function: LoadGameMat
        function LoadGameMatClicked(app, event)
            [file, path] = uigetfile({'*.mat', 'Clueless Games(*.mat)'}, 'Load...', app.curpath);
            if isMATLABReleaseOlderThan("R2025a")
                figure(app.clFigure);
            end
            if isequal(file, 0) || isequal(path, 0)
                return
            end
            h = uiprogressdlg(app.clFigure, Title="Loading " + file + '...', Message="Please wait a moment...", ...
                Indeterminate="on");drawnow
            try
                Gdata = importdata([path, file]);
            catch error
                if ~isempty(app.speaker)
                    SpeakAsync(app.speaker, 'Load Error');
                end
                close(h);
                uialert(app.clFigure, error.message, "Game Not Found!😧", Icon="error");
                return
            end
            fieldsGdata = fieldnames(Gdata);
            fieldsExpected = {'t', 'm', 'n', 'pnum', 'yourcards', 'commoncards', 'moves', 'sol'};
            if ~all(ismember(fieldsExpected, fieldsGdata))
                if ~isempty(app.speaker)
                    SpeakAsync(app.speaker, 'Load Error');
                end
                close(h);
                uialert(app.clFigure, "Invalid Clueless Game File!", "Game Not Found!😧", Icon="error");
                return
            end
            lenGdata = length(Gdata);
            if ~ismember('title', fieldsGdata)
                if lenGdata == 1
                    Gdata.title = string(file);
                else
                    for g = 1:lenGdata
                        Gdata(g).title = string(file) + ": #" + g;
                    end
                end
            end
            app.LoadGame.Items = [string(app.LoadGame.Items), [Gdata(:).title]];
            app.LoadGame.ItemsData(end+1:end+lenGdata) = app.LoadGame.ItemsData(end) + (1:lenGdata);
            app.LoadGame.Value = app.LoadGame.ItemsData(end);
            app.games(end+1:end+lenGdata) = Gdata;
            set(h, Message=[file, ' loaded.'], Icon='success');drawnow
            close(h);
            app.curpath = path;
        end

        % Callback function: SaveGameMat
        function SaveGameMatClicked(app, event)
            if isempty(app.moves)
                uialert(app.clFigure, "Play / Solve a game before trying to save one...", "Nothing to Save!🤷", ...
                    Icon="warning");
                return
            end
            [file, path] = uiputfile({'*.mat', 'Clueless Games(*.mat)'}, 'Save...', fullfile(app.curpath, 'CGame.mat'));
            if isMATLABReleaseOlderThan("R2025a")
                figure(app.clFigure);
            end
            if isequal(file, 0) || isequal(path, 0) || file(1) == '.'
                return
            else
                h = uiprogressdlg(app.clFigure, Title="Saving the Game...", Message="Please wait a moment...", ...
                    Indeterminate="on");drawnow
                [ext, file] = strtok(fliplr(file), '.');
                if isempty(file)
                    file = fliplr(ext);
                else
                    file = strtok(fliplr(file), '.');
                end
            end
            CG = struct('t', {}, 'm', {}, 'n', {}, 'pnum', {}, ...
                'yourcards', {}, 'commoncards', {}, 'moves', {}, 'sol', {});
            for p = 1:app.params(2)
                if ~all(~app.moves(:, :, p), "all")
                    CG(end+1) = struct('t', {app.params(1)}, 'm', {app.params(2)}, 'n', {app.params(3)}, ...
                        'pnum', {p}, 'yourcards', {app.plyrCards(p,:)}, 'commoncards', {app.commonCards}, ...
                        'moves', {app.moves(:, :, p)}, 'sol', {app.solV}); %#ok<AGROW>
                end
            end
            save(fullfile(path, [file, '.mat']), 'CG');
            set(h, Message="Game saved in " + file + ".mat.", Icon="success");drawnow
            close(h);
        end

        % Callback function: AppTheme
        function AppThemeClicked(app, event)
            if app.AppTheme.State % Dark Mode
                app.clFigure.Theme.BaseColorStyle = "dark";
                app.AppTheme.Tooltip = "Switch to Light Mode";
            else % Light Mode
                app.clFigure.Theme.BaseColorStyle = "light";
                app.AppTheme.Tooltip = "Switch to Dark Mode";
            end
            app.Moves.BackgroundColor = fliplightness(app.Moves.BackgroundColor);
        end

        % Callback function: About
        function AboutClicked(app, event)
            if ~isempty(app.speaker)
                if strcmpi(char(ToString(app.speaker.State)), 'Ready')
                    SpeakAsync(app.speaker, ['Many thanks to Matt Tearle and Chen Lin, ', ...
                        'the organizers of Cody Contest 2025. ', 'Special thanks to my fellow Cody participants: ', ...
                        'Stefan Abendroth, WANG Zi-Xiang and Vasilis Bellos.']);
                end
            end
            abt=["Clueless"; "Lord Ned in the Game Room"; ...
                "<a href=""https://www.mathworks.com/matlabcentral/cody/groups/96682/problems/61069"">" + ...
                "Problem 61069 of Cody Contest 2025</a>"; " "; "Version " + app.majVer + "." + app.minVer + ...
                ".χ; Release " + erase(app.relz, " "); string(app.relzDate, "MMMM dd, yyyy"); " "; ...
                "Cody Contest 2025: Matt Tearle and Chen Lin"; " "; ...
                "Algorithm: ⬇️, S. Abendroth, Z.-X. Wang and V. Bellos"; "©️2026 JKMSMKJ"];
            uialert(app.clFigure, abt, "About", Icon="info", Interpreter="html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.clFigure = uifigure('Visible', 'off');
            app.clFigure.Name = 'Clueless Game';
            app.clFigure.Icon = fullfile(pathToMLAPP, 'images', 'clueless_icon.png');
            app.clFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.Toolbar = uitoolbar(app.clFigure);
            app.LoadGameMat = uipushtool(app.Toolbar);
            app.LoadGameMat.Tooltip = {'Load Game(s)...'};
            app.LoadGameMat.ClickedCallback = createCallbackFcn(app, @LoadGameMatClicked, true);
            app.LoadGameMat.Icon = fullfile(pathToMLAPP, 'images', 'open_tb_image.png');
            app.SaveGameMat = uipushtool(app.Toolbar);
            app.SaveGameMat.Tooltip = {'Save Current Game...'};
            app.SaveGameMat.ClickedCallback = createCallbackFcn(app, @SaveGameMatClicked, true);
            app.SaveGameMat.Icon = fullfile(pathToMLAPP, 'images', 'save_tb_image.png');
            app.AppTheme = uitoggletool(app.Toolbar);
            app.AppTheme.Tooltip = {'Switch to Dark Mode'};
            app.AppTheme.ClickedCallback = createCallbackFcn(app, @AppThemeClicked, true);
            app.AppTheme.Enable = 'off';
            app.AppTheme.Icon = fullfile(pathToMLAPP, 'images', 'theme_tb_image.png');
            app.AppTheme.Separator = 'on';
            app.About = uipushtool(app.Toolbar);
            app.About.Tooltip = {'About'};
            app.About.ClickedCallback = createCallbackFcn(app, @AboutClicked, true);
            app.About.Icon = fullfile(pathToMLAPP, 'images', 'about_tb_image.png');
            app.About.Separator = 'on';
            app.GclBoard = uigridlayout(app.clFigure);
            app.clBoard = uipanel(app.GclBoard);
            app.GclLayout = uigridlayout(app.clBoard);
            app.GameSetupPanel = uipanel(app.GclLayout);
            app.GameSetupPanel.Title = '💂Game Setup';
            app.Gsetup = uigridlayout(app.GameSetupPanel);
            app.NumCatsLabel = uilabel(app.Gsetup);
            app.NumCatsLabel.Text = 'Number of Categories:';
            app.LoadGame = uidropdown(app.Gsetup);
            app.LoadGame.Items = {};
            app.LoadGame.Tooltip = {'Select a Game'};
            app.LoadGame.Value = {};
            app.NumCats = uispinner(app.Gsetup);
            app.NumCats.Limits = [3 5];
            app.NumCats.RoundFractionalValues = 'on';
            app.NumCats.ValueDisplayFormat = '%.0f';
            app.NumCats.ValueChangedFcn = createCallbackFcn(app, @NumCatsValueChanged, true);
            app.NumCats.Enable = 'off';
            app.NumCats.Value = 3;
            app.NumCardsLabel = uilabel(app.Gsetup);
            app.NumCardsLabel.Text = 'Number of Cards:';
            app.NumCards = uispinner(app.Gsetup);
            app.NumCards.Limits = [4 8];
            app.NumCards.RoundFractionalValues = 'on';
            app.NumCards.ValueDisplayFormat = '%.0f';
            app.NumCards.ValueChangedFcn = createCallbackFcn(app, @NumCardsValueChanged, true);
            app.NumCards.Enable = 'off';
            app.NumCards.Value = 4;
            app.NumPlayersLabel = uilabel(app.Gsetup);
            app.NumPlayersLabel.Text = 'Number of Players:';
            app.NumPlayers = uispinner(app.Gsetup);
            app.NumPlayers.Limits = [4 8];
            app.NumPlayers.RoundFractionalValues = 'on';
            app.NumPlayers.ValueDisplayFormat = '%.0f';
            app.NumPlayers.ValueChangedFcn = createCallbackFcn(app, @NumPlayersValueChanged, true);
            app.NumPlayers.Enable = 'off';
            app.NumPlayers.Value = 4;
            app.NumYouLabel = uilabel(app.Gsetup);
            app.NumYouLabel.Text = 'You are Player #:';
            app.NumYou = uispinner(app.Gsetup);
            app.NumYou.Limits = [1 4];
            app.NumYou.RoundFractionalValues = 'on';
            app.NumYou.ValueDisplayFormat = '%.0f';
            app.NumYou.Enable = 'off';
            app.NumYou.Value = 4;
            app.LetsPlay = uibutton(app.Gsetup, 'push');
            app.LetsPlay.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlay.Text = {'👑Lord Ned Commands:'; ''; 'Let''s Begin the Game!🤔'};
            app.ModeSwitch = uiswitch(app.Gsetup, 'slider');
            app.ModeSwitch.Items = {'Play Mode', 'Solve Mode'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Tooltip = {'Choose a Mode'};
            app.ModeSwitch.Value = 'Solve Mode';
            app.CardsDistPanel = uipanel(app.GclLayout);
            app.CardsDistPanel.Enable = 'off';
            app.CardsDistPanel.Title = '🎴Cards Distribution';
            app.Gcards = uigridlayout(app.CardsDistPanel);
            app.PlayersCsLabel = uilabel(app.Gcards);
            app.PlayersCsLabel.Tooltip = {'Player 1'};
            app.PlayersCsLabel.Text = '👤1';
            app.PlayersC1 = uieditfield(app.Gcards, 'text');
            app.PlayersC1.Editable = 'off';
            app.PlayersC1.Value = '{?}';
            app.PlayersC2 = uieditfield(app.Gcards, 'text');
            app.PlayersC2.Editable = 'off';
            app.PlayersC2.Value = '{?}';
            app.PlayersC3 = uieditfield(app.Gcards, 'text');
            app.PlayersC3.Editable = 'off';
            app.PlayersC3.Value = '{?}';
            app.CommonCsLabel = uilabel(app.Gcards);
            app.CommonCsLabel.Tooltip = {'Common'};
            app.CommonCsLabel.Text = '🃏';
            app.CommonC1 = uieditfield(app.Gcards, 'text');
            app.CommonC1.Editable = 'off';
            app.CommonC1.Value = '{?}';
            app.CommonC2 = uieditfield(app.Gcards, 'text');
            app.CommonC2.Editable = 'off';
            app.CommonC2.Value = '{?}';
            app.CommonC3 = uieditfield(app.Gcards, 'text');
            app.CommonC3.Editable = 'off';
            app.CommonC3.Value = '{?}';
            app.EnvelopeCsLabel = uilabel(app.Gcards);
            app.EnvelopeCsLabel.Tooltip = {'Envelope'};
            app.EnvelopeCsLabel.Text = '✉️';
            app.EnvelopeC1 = uispinner(app.Gcards);
            app.EnvelopeC1.Limits = [0 4];
            app.EnvelopeC1.RoundFractionalValues = 'on';
            app.EnvelopeC1.ValueDisplayFormat = '%.0f';
            app.EnvelopeC2 = uispinner(app.Gcards);
            app.EnvelopeC2.Limits = [0 4];
            app.EnvelopeC2.RoundFractionalValues = 'on';
            app.EnvelopeC2.ValueDisplayFormat = '%.0f';
            app.EnvelopeC3 = uispinner(app.Gcards);
            app.EnvelopeC3.Limits = [0 4];
            app.EnvelopeC3.RoundFractionalValues = 'on';
            app.EnvelopeC3.ValueDisplayFormat = '%.0f';
            app.Check = uibutton(app.Gcards, 'push');
            app.Check.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.Check.Text = '☝️Check Your Solution☝️';
            app.MovesListPanel = uipanel(app.GclLayout);
            app.MovesListPanel.Enable = 'off';
            app.MovesListPanel.Title = '📋Moves List';
            app.Gmoves = uigridlayout(app.MovesListPanel);
            app.Moves = uitable(app.Gmoves);
            app.Moves.ColumnName = {'Player'; 'Asked'; 'Cat.1'; 'Cat.2'; 'Cat.3'; 'Reply'};
            app.Moves.SelectionType = 'row';
            app.AskPlayer = uidropdown(app.Gmoves);
            app.AskPlayer.Items = {};
            app.AskPlayer.Visible = 'off';
            app.AskPlayer.Tooltip = {'Pick a Player'};
            app.AskPlayer.Value = {};
            app.AskC1 = uispinner(app.Gmoves);
            app.AskC1.Limits = [1 4];
            app.AskC1.RoundFractionalValues = 'on';
            app.AskC1.ValueDisplayFormat = '%.0f';
            app.AskC1.Value = 1;
            app.AskC2 = uispinner(app.Gmoves);
            app.AskC2.Limits = [1 4];
            app.AskC2.RoundFractionalValues = 'on';
            app.AskC2.ValueDisplayFormat = '%.0f';
            app.AskC2.Value = 1;
            app.AskC3 = uispinner(app.Gmoves);
            app.AskC3.Limits = [1 4];
            app.AskC3.RoundFractionalValues = 'on';
            app.AskC3.ValueDisplayFormat = '%.0f';
            app.AskC3.Value = 1;
            app.ReplyDD = uidropdown(app.Gmoves);
            app.ReplyDD.Items = {};
            app.ReplyDD.Visible = 'off';
            app.ReplyDD.Tooltip = {'Your Reply'};
            app.ReplyDD.Value = {};
            app.AskButton = uibutton(app.Gmoves, 'push');
            app.AskButton.ButtonPushedFcn = createCallbackFcn(app, @AskButtonButtonPushed, true);
            app.AskButton.Text = '➡️Ask';
            app.NextMove = uibutton(app.Gmoves, 'push');
            app.NextMove.ButtonPushedFcn = createCallbackFcn(app, @NextMoveButtonPushed, true);
            app.NextMove.Text = '➡️Next Move➡️';
            % Show the figure after all components are created
            app.clFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clueless

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.clFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.clFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.clFigure)
        end
    end
end